classdef DeteccionSonrisa_Baldovi_Martin3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        mostrar_Histograma_Grad         matlab.ui.control.Button
        mostrar_Histograma_Rojo         matlab.ui.control.Button
        ArreglarbrilloButton            matlab.ui.control.Button
        imagen_superp_checkbox          matlab.ui.control.CheckBox
        mostrar_Histograma              matlab.ui.control.Button
        ValoresparalosumbralespersonalizadosLabel  matlab.ui.control.Label
        GradienteCheckBox               matlab.ui.control.CheckBox
        CanalRojoCheckBox               matlab.ui.control.CheckBox
        GrisesCheckBox                  matlab.ui.control.CheckBox
        segm_valorUmbralGrad_editText   matlab.ui.control.NumericEditField
        segm_valorUmbralRojo_editText   matlab.ui.control.NumericEditField
        segm_valorUmbral_editText       matlab.ui.control.NumericEditField
        TiposdesegmentadoLabel          matlab.ui.control.Label
        UsarvaloresautomaticosCheckBox  matlab.ui.control.CheckBox
        reapxEditField                  matlab.ui.control.NumericEditField
        reapxEditFieldLabel             matlab.ui.control.Label
        TamaolmiteobjetosaeliminarLabel  matlab.ui.control.Label
        ProcesoLabel                    matlab.ui.control.Label
        OriginalLabel                   matlab.ui.control.Label
        morf_sum_checkbox               matlab.ui.control.CheckBox
        morf_valorClose_V_editText      matlab.ui.control.NumericEditField
        TamaodelamascaraLabel_4         matlab.ui.control.Label
        morf_valorClose_T_editText      matlab.ui.control.NumericEditField
        TamaodelamascaraLabel_3         matlab.ui.control.Label
        OperacionCloseLabel             matlab.ui.control.Label
        morf_valorOpen_V_editText       matlab.ui.control.NumericEditField
        TamaodelamascaraLabel_2         matlab.ui.control.Label
        OperacionOpenLabel              matlab.ui.control.Label
        morf_valorOpen_T_editText       matlab.ui.control.NumericEditField
        TamaodelamascaraLabel           matlab.ui.control.Label
        morf_auto_checkbox              matlab.ui.control.CheckBox
        segm_auto_checkbox              matlab.ui.control.CheckBox
        reset                           matlab.ui.control.Button
        recon_boca                      matlab.ui.control.Button
        desc_partes_cara                matlab.ui.control.Button
        morf_partes_cara                matlab.ui.control.Button
        segm_partes_cara                matlab.ui.control.Button
        saveas                          matlab.ui.control.Button
        open_file                       matlab.ui.control.Button
        imag_proc                       matlab.ui.control.UIAxes
        imag_orig                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        imag_orig_mat % pixel values of the original image
        imag_proc_mat % pixel values of the processed image
        imag_recortada_orig
        ya_morf % 1=ya se ha hecho morfologia, 0= aun no se ha hecho morfologia, -1=se hace morfologia desde el principio
        regionBoca
        area_max_desc
        imagen_brillo_retocado % la imagen resultante de hacer el retoque de brillo
    end
    
    methods (Access = private)
        
        %--- funcion para pasar la imagen en color a escala de grises
        % ------------------------------------------------------------------------------------------------------------------------------------------------
        function results = imgAEscalaDeGrises(app, img)
            if size(img, 3) == 3
                results = rgb2gray(img); %post de StackOverflow
            else
                results = img;
            end
        end
        
        %--- funcion para mostrar la imagen en el axis
        % ------------------------------------------------------------------------------------------------------------------------------------------------
        function showImgEnAxis(app, img) %documentacion de MatLab

            if get(app.imagen_superp_checkbox, 'Value')==1
                % codigo para mostrar la imagen binaria por encima de la original
                A=labeloverlay(app.imag_orig_mat, img);
                imshow(A,'Parent',app.imag_orig,'InitialMagnification','fit');
            else
                imshow(app.imag_orig_mat,'Parent',app.imag_orig,'InitialMagnification','fit');
            end

            %codigo para mostrar la imagen recibida (la procesada) en el segundo axis
            imshow(img,'Parent',app.imag_proc,'InitialMagnification','fit');
        end
        
        %funcion para crear el histograma de la imagen
        % ------------------------------------------------------------------------------------------------------------------------------------------------
        function results = makeHistogramaImg(app, img) %un post de stackOverflow
            if size(img, 3)==3
                histograma = zeros(256,3);
                for canal = 1:3
                    histograma(:, canal) = imhist(img(:, :, canal));
                end
                results=histograma;
            else
                results=imhist(img);
            end
           
        end
        
        %--- algoritmo para encontrar el mejor valor en el cual umbralizar para la segmentacion
        % mediaEntrePicos
        % ------------------------------------------------------------------------------------------------------------------------------------------------
        function results = encontrarMediaEntrePicos(app, img)
            if size(img, 3) == 1

                luminoso=detectarLuminosidad(app, img);
                if luminoso>170
                    results=127/255;
                    return
                end

                [histograma, valoresX] = imhist(img);%documentacion de MatLab
                posiciones_validas = histograma > 0;
                valorMaximo = max(histograma);%documentacion de MatLab

                [picos, posiciones] = findpeaks(histograma(posiciones_validas),"MinPeakProminence",valorMaximo*0.09);%documentacion de MatLab

                %si el primer pico esta mas allá de 125 la imagen tiene
                %problemas de iluminacion y ponemos el umbral mas pequeño
                if valoresX(posiciones(1))>125
                    results=80/255;
                    return
                end

                if numel(picos) >= 2
                    mediaPicos = mean(valoresX(posiciones(1:2)));
                    mediaPicos2=mediaPicos/255;
                    results = round(mediaPicos2, 2);
                else
                    results=valoresX(1);
                    %no se encontraron 2 picos en el histograma
                end
            else
                %la imagen no esta en escala de grises
                results=0;
            end
        end
        
        % segmentado: grises
        % ------------------------------------------------------------------------------------------------------------------------------------------------
        function results = segmentadoGrises(app, imagenRecibida)
            imagenGrises = imagenRecibida;
            if get(app.segm_auto_checkbox, 'Value')==1
                valorUmbral = encontrarMediaEntrePicos(app, imagenGrises);
                if valorUmbral==0
                    app.segm_valorUmbral_editText.Value=(valorUmbral*255)+1;
                else
                    app.segm_valorUmbral_editText.Value=(valorUmbral*255);
                end
            else
                valorUmbral=(app.segm_valorUmbral_editText.Value/255);
            end
            app.segm_valorUmbral_editText.Value=round(valorUmbral*255);
            imagenUmbralizada = ~imbinarize(imagenGrises, valorUmbral);
            se3 = strel('disk', 3);
            imagenFinal = imdilate(imagenUmbralizada,se3);
            results=imagenFinal;
        end
        
        % segmentado: canal rojo
        % ------------------------------------------------------------------------------------------------------------------------------------------------
        function results = segmentadoRojo(app, imagenRecibida)
            [R,G,B]=imsplit(imagenRecibida); 

            %no usamos el canal R ya que el rojo se puede observar por la
            %ausencia en su canal y el color rosado esta mas presente en G (nos valdria tanto G como B)

            if get(app.segm_auto_checkbox, 'Value')==1
                valorUmbral=encontrarMediaEntrePicos(app, G);
                lumin=detectarLuminosidad(app, G);
                if (valorUmbral==80/255)
                    if lumin<157
                        valorUmbral=35/255;
                    elseif (lumin<185 && lumin>160)
                        valorUmbral=130/255;
                        if (lumin>177 && lumin<179)
                            valorUmbral=80/255;
                        end
                    end
                elseif (valorUmbral>78/255 && valorUmbral<80/255)  || (valorUmbral>88/255 && valorUmbral<90/255)
                    valorUmbral=120/255;
                elseif (valorUmbral>75/255 && valorUmbral<77/255)
                    valorUmbral=100/255;
                end
                app.segm_valorUmbralRojo_editText.Value=round(valorUmbral*255);
            else
                valorUmbral=app.segm_valorUmbralRojo_editText.Value/255;
            end
            imagenUmbralizada = ~imbinarize(G, valorUmbral);
            results=imagenUmbralizada;
        end
        
        % segmentado: gradiente
        % ------------------------------------------------------------------------------------------------------------------------------------------------
        function results = segmentadoGradiente(app, imagenRecibida)
            if get(app.segm_auto_checkbox, 'Value')==1
                [~,threshold] = edge(imagenRecibida,'sobel');

                threshold2=((threshold*3)+3e-02)/4;

                app.segm_valorUmbralGrad_editText.Value=round((threshold2*100), 3);
            else
                threshold2=app.segm_valorUmbralGrad_editText.Value/100;
            end
            BWs = edge(imagenRecibida,'sobel',threshold2);

            %[Gmag,Gdir]=imgradient(imagenRecibida, 'prewitt');
            %imshowpair(Gmag, Gdir, 'montage');


            se3 = strel('disk', 3);
            se9 = strel('square', 9);

            se90 = strel('line',5,90);
            se0 = strel('line',7,0);

            BWsdil = imdilate(BWs,[se90 se0]);

            imagenCerrada = imclose(BWsdil, se3);
            imagenCerrada2 = imopen(imagenCerrada, se9);
            results=imagenCerrada2;
        end
        
        % comprobar checkbox
        % ------------------------------------------------------------------------------------------------------------------------------------------------
        function comprobarCheckbox(app)
            %elementos de control de seccion Segmentacion
            checkBoxAutoSegmentacion = app.segm_auto_checkbox;
            editFieldUmbralSegmentacion=app.segm_valorUmbral_editText;
            editFieldRojoSegmentacion=app.segm_valorUmbralRojo_editText;
            editFieldGradienteSegmentacion=app.segm_valorUmbralGrad_editText;

            %elementos de control de seccion morfologia
            checkBoxAutoMorfologia=app.morf_auto_checkbox;
            editFieldOpenTamano=app.morf_valorOpen_T_editText;
            editFieldOpenVeces=app.morf_valorOpen_V_editText;
            editFieldCloseTamano=app.morf_valorClose_T_editText;
            editFieldCloseVeces=app.morf_valorClose_V_editText;

            % 0 = no marcado 
            % 1 = marcado
            estadoCheckBoxAutoSegm=get(checkBoxAutoSegmentacion, 'Value');
            estadoCheckBoxAutoMorf=get(checkBoxAutoMorfologia, 'Value');

            if estadoCheckBoxAutoSegm==1
                set(editFieldUmbralSegmentacion, 'Enable', 'off');
                set(editFieldRojoSegmentacion, 'Enable', 'off');
                set(editFieldGradienteSegmentacion, 'Enable', 'off');
            elseif estadoCheckBoxAutoSegm==0
                set(editFieldUmbralSegmentacion, 'Enable', 'on');
                set(editFieldRojoSegmentacion, 'Enable', 'on');
                set(editFieldGradienteSegmentacion, 'Enable', 'on');
            end

            if estadoCheckBoxAutoMorf==1
                set(editFieldOpenTamano, 'Enable', 'off');
                set(editFieldOpenVeces, 'Enable', 'off');

                set(editFieldCloseTamano, 'Enable', 'off');
                set(editFieldCloseVeces, 'Enable', 'off');

            elseif estadoCheckBoxAutoMorf==0
                set(editFieldOpenTamano, 'Enable', 'on');
                set(editFieldOpenVeces, 'Enable', 'on');

                set(editFieldCloseTamano, 'Enable', 'on');
                set(editFieldCloseVeces, 'Enable', 'on');
            end
        end
        
        % detecta si la imagen es demasiado luminosa u oscura para usar la funcion de encontrarMediaEntrePicos
        % ------------------------------------------------------------------------------------------------------------------------------------------------
        function results = detectarLuminosidad(app, img)
            luminosidadPromedio = mean(img(:));
            %umbralMin=85;
            %umbralMax=170;

            results=luminosidadPromedio;

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            axis(app.imag_orig, 'image'); 
        end

        % Button pushed function: open_file
        function open_fileButtonPushed(app, event)
            
            %---- funcion venia de ejemplo

            [filename, pathname]= uigetfile({'*.jpg;*.tif;*.png;*.gif','All Image Files'; '*.*','All Files' },'Choose an image file ...');
            
            if isequal(filename,0)
               warndlg('Archivo no seleccionado');
               return
            end
            
            image=imread(strcat(pathname,filename));
            
            if not(size(image,3)==3)
               errordlg('La imagen debe ser en color');
               return
            end
            
            imshow(image,'Parent',app.imag_orig,'InitialMagnification','fit');
            
            %borramos lo que puediera haber antes
            cla(app.imag_proc);
            app.imag_proc_mat=[];
            app.imagen_brillo_retocado=[];

            app.imag_orig_mat=image;

            % Traer la figura de la UI al frente
            figure(app.UIFigure);
        end

        % Button pushed function: saveas
        function saveasButtonPushed(app, event)
            
            %---- funcion venia de ejemplo

            [filename, pathname]= uiputfile({'*.jpg;*.tif;*.png;*.gif','All Image Files'; '*.*','All Files' },'Save image file as ...');
            
            if isequal(filename,0)
               warndlg('Archivo no seleccionado');
               return
            end
            
            image=app.imag_proc_mat;
            
            imwrite(image,strcat(pathname,filename));    
        end

        % Button pushed function: segm_partes_cara
        function segm_partes_caraButtonPushed(app, event)
            %funcion original

            imagenOriginal=app.imag_orig_mat;
            if (~isempty(app.imagen_brillo_retocado))
                imagenBrilloRetocado=app.imagen_brillo_retocado;
            else
                imagenBrilloRetocado=imgAEscalaDeGrises(app,imagenOriginal);
            end
            if(~isempty(imagenOriginal))

                [filasOriginal, columnasOriginal, canalesOriginal] = size(imagenOriginal);
                mitad_inferior = round(filasOriginal / 2):filasOriginal;
                imagenRecortada=imagenOriginal(mitad_inferior,:,:);
                [filasRecort, columnasRecort] = size(imagenRecortada);
                filasAgregar=filasOriginal-filasRecort;

                imagenRecortadaBrilloParaGrises=imagenBrilloRetocado(mitad_inferior,:,:);

                %caso umbralizacion simple

                imagenRecortadaUmbralS=segmentadoGrises(app, imagenRecortadaBrilloParaGrises);

                %caso umbralizacionCanalRojo

                imagenRecortadaUmbralRojos=segmentadoRojo(app, imagenRecortada);

                %caso umbralizacion por gradiente

                imagenRecortadaGradiente=segmentadoGradiente(app, imagenRecortadaBrilloParaGrises);

                %vuelta a tamaño original
                imagenExpandidaUmbralS=[zeros(filasAgregar, columnasOriginal); imagenRecortadaUmbralS];
                imagenExpandidaUmbralRojo=[zeros(filasAgregar, columnasOriginal); imagenRecortadaUmbralRojos];
                imagenExpandidaGradiente=[zeros(filasAgregar, columnasOriginal); imagenRecortadaGradiente];


                %combinar las tres imagenes segun los checks
                checkGris=get(app.GrisesCheckBox, 'Value');
                checkRojo=get(app.CanalRojoCheckBox, 'Value');
                checkGrad=get(app.GradienteCheckBox, 'Value');

                %comprobar que tipos de segmentacion se hacen segun lo que se tenga marcado
                if checkGris==1
                    if checkRojo==1
                        if checkGrad==1
                            imagen_combinada = (imagenExpandidaUmbralRojo + imagenExpandidaUmbralS +imagenExpandidaGradiente) >= 2;
                        else
                            imagen_combinada = (imagenExpandidaUmbralRojo + imagenExpandidaUmbralS) >= 2;
                        end
                    else
                        if checkGrad==1
                            imagen_combinada = (imagenExpandidaUmbralS +imagenExpandidaGradiente) >= 2;
                        else
                            imagen_combinada = (imagenExpandidaUmbralS) >= 1;
                        end
                    end
                else
                    if checkRojo==1
                        if checkGrad==1
                            imagen_combinada = (imagenExpandidaUmbralRojo +imagenExpandidaGradiente) >= 2;
                        else
                            imagen_combinada = (imagenExpandidaUmbralRojo) >= 1;
                        end
                    else
                        if checkGrad==1
                            imagen_combinada = (imagenExpandidaGradiente) >= 1;
                        else
                            return
                        end
                    end
                end

                %mostrar en pantalla
                %imshow(imagenExpandidaUmbralS,'Parent',app.imag_proc,'InitialMagnification','fit');
                app.imag_recortada_orig=imagen_combinada;
                app.imag_proc_mat=imagen_combinada;
                showImgEnAxis(app, imagen_combinada);
                app.ya_morf=0; %la imagen aun no se ha pasado por morfologia
            else
                warndlg('Imagen no seleccionada');
                comprobarCheckbox(app);
            end
        end

        % Callback function: not associated with a component
        function mostrar_HistogramaButtonPushed(app, event)
            
        end

        % Button pushed function: morf_partes_cara
        function morf_partes_caraButtonPushed(app, event)
            % funcion original

            %comrprobamos que no este activada la opcion de que se sumen las morfologias y que ya se ha hecho una morfologia previamente
            if ((get(app.morf_sum_checkbox, 'Value')==0) && (app.ya_morf==1))
                return
            end
            
            %comprobamos que no se hayan cambiado los valores de morfologia antes de hacer otra morfologia
            if app.ya_morf==-1
                imagenParaTrabajar=app.imag_recortada_orig;
            else
                imagenParaTrabajar = app.imag_proc_mat;
            end

            if(islogical(imagenParaTrabajar))
                
                %comprobamos si se utilizan valores personalizados
                if get(app.morf_auto_checkbox, 'Value')==0
                    numCierres=app.morf_valorClose_V_editText.Value;
                    numOpens=app.morf_valorOpen_V_editText.Value;
                    tamanoMascaraOpen=app.morf_valorOpen_T_editText.Value;
                    tamanoMascaraClose=app.morf_valorClose_T_editText.Value;
                else
                    numCierres=5;
                    numOpens=5;
                    tamanoMascaraOpen=5;
                    tamanoMascaraClose=5;

                    app.morf_valorClose_V_editText.Value=numCierres;
                    app.morf_valorOpen_V_editText.Value=numOpens;
                    app.morf_valorOpen_T_editText.Value=tamanoMascaraOpen;
                    app.morf_valorClose_T_editText.Value=tamanoMascaraClose;
                end

                sel= strel('line', 19, 0);
                seltop= strel('line', 7, 90);

                imagenParaTrabajar=imerode(imagenParaTrabajar, seltop);

                imagenParaTrabajar=imclearborder(imagenParaTrabajar);

                imagenParaTrabajar=bwmorph(imagenParaTrabajar, "majority");
                imagenParaTrabajar=imdilate(imagenParaTrabajar, sel);

                se3 = strel('disk', 3);
                imagenParaTrabajar=imdilate(imagenParaTrabajar, se3);

                seOpen = strel('disk', tamanoMascaraOpen);
                seClose = strel('disk', tamanoMascaraClose);

                for i = 1:numCierres
                    imagenParaTrabajar = imclose(imagenParaTrabajar, seClose);
                end
                for i = 1:numOpens
                    imagenParaTrabajar = imopen(imagenParaTrabajar, seOpen);
                end

                imagenParaTrabajar=imclearborder(imagenParaTrabajar);

                imagenParaTrabajar=imfill(imagenParaTrabajar, "holes");

                imagenFinal=imagenParaTrabajar;
                app.imag_proc_mat = imagenFinal;
                showImgEnAxis(app, imagenFinal);
                app.ya_morf=1;%ya se ha hecho al menos una vez morfologia

            elseif(isempty(imagenParaTrabajar))
                warndlg('Primero debes segmentar');
            else
                warndlg('Error en la imagen');
            end
        end

        % Button pushed function: reset
        function resetButtonPushed(app, event)
            %funcion original
            
            cla(app.imag_orig);
            cla(app.imag_proc);
            app.imag_orig_mat = [];
            app.imag_proc_mat = [];
            app.imagen_brillo_retocado=[];
            comprobarCheckbox(app);
        end

        % Value changed function: segm_auto_checkbox
        function segm_auto_checkboxValueChanged(app, event)
            comprobarCheckbox(app);
        end

        % Value changed function: morf_auto_checkbox
        function morf_auto_checkboxValueChanged(app, event)
            comprobarCheckbox(app);
            app.ya_morf=-1;
        end

        % Value changed function: morf_valorClose_T_editText, 
        % ...and 3 other components
        function morf_mascaraChanged(app, event)
            app.ya_morf=-1;
        end

        % Value changed function: imagen_superp_checkbox
        function imagen_superp_checkboxValueChanged(app, event)
            if ~isempty(app.imag_proc_mat)
                showImgEnAxis(app, app.imag_proc_mat)
            end
        end

        % Button pushed function: desc_partes_cara
        function desc_partes_caraButtonPushed(app, event)
            % Cogemos la imagen procesada
            imagenParaDescriptores = app.imag_proc_mat;
            
            if(islogical(imagenParaDescriptores))
                try
                    % Obtenemos los objetos de la imagen binaria y las
                    % propiedades de estos
                    objetos = bwconncomp(imagenParaDescriptores);
                    region = regionprops(objetos, 'Area', 'Solidity', 'Perimeter', 'Centroid', 'BoundingBox', 'PixelIdxList');

                    % Filtra objetos por área mínima esperada para la boca
                    areaMinima = app.reapxEditField.Value; % Este valor es ajustable segun que tan pequenyos son los objetos a eliminar
     
                    % Creamos una imagen binaria vacía del mismo tamaño que la original
                    % Esto para guardar todos los objetos menos los pequenyos
                    % que hemos eliminado
                    imagenSP = false(size(imagenParaDescriptores));

                    % Filtramos objetos por área mínima y los anyadimos a la imagen binaria
                    for i = 1:length(region)
                        if region(i).Area > areaMinima
                            imagenSP(region(i).PixelIdxList) = true;
                        end
                    end
                    objetosFiltradosSinPequenyos = bwconncomp(imagenSP);
                    regionNP = regionprops(objetosFiltradosSinPequenyos, 'Area', 'Solidity', 'Perimeter', 'Centroid', 'BoundingBox');

                    % Inicializar variables para encontrar la boca
                    [alturaImagen, anchoImagen] = size(imagenParaDescriptores);  % Obtiene las dimensiones de la imagen
                    centroImagenX = anchoImagen / 2;  % Calcula el centro horizontal
                    umbralY = alturaImagen * 0.5;  % Define un umbral para considerar la parte inferior de la imagenminDistanciaCentro = inf;
                    idxBoca = 0;
                    minDistanciaCentro = inf;
                    areaMinima = 20; % Define un área mínima esperada para la boca para evitar objetos muy pequeños

                    % Tratamos de encontrar la boca segun la posicion (la boca
                    % suele estar en la mitad de abajo de la imagen y en la
                    % parte central)
                    for i = 1:length(regionNP)
                        centroide = regionNP(i).Centroid;
                        boundingBox = regionNP(i).BoundingBox;
                        areaObjeto = regionNP(i).Area;

                        % Calcular la anchura y la altura del objeto
                        anchura = boundingBox(3);
                        altura = boundingBox(4);

                        % Verificar si el objeto está en la parte inferior y centrada
                        esInferior = centroide(2) > umbralY;
                        distanciaCentro = abs(centroide(1) - centroImagenX);
                        esAncho = anchura > altura;

                        % Seleccionar el objeto si cumple con los criterios y está más cerca al centro
                        if esInferior && esAncho && distanciaCentro < minDistanciaCentro && areaObjeto > areaMinima
                            minDistanciaCentro = distanciaCentro;
                            idxBoca = i;
                        end
                    end

                    % Una vez obtenida la boca la guardamos en una imagen vacia
                    % del tamanyo de la original
                    imagenFiltrada = false(size(imagenParaDescriptores));
                    pixelesBoca = objetosFiltradosSinPequenyos.PixelIdxList{idxBoca};
                    imagenFiltrada(pixelesBoca) = true;

                    % Sacamos las propiedades de la boca
                    objetosBoca = bwconncomp(imagenFiltrada);
                    regionBoca = regionprops(objetosBoca, 'Area', 'Solidity', 'Perimeter', 'Centroid', 'BoundingBox', 'Eccentricity', 'MajorAxisLength', 'MinorAxisLength');

                    app.regionBoca = regionBoca;
                    showImgEnAxis(app, imagenFiltrada);
                catch ME
                    warndlg('Valor de area maxima demasiado grande, por favor, ajuste el valor y pruebe de nuevo');
                end
            elseif(isempty(imagenParaDescriptores))
                warndlg('Primero debes segmentar y/o hacer la morfologia');
            else
                warndlg('Error en la imagen');
            end
        end

        % Button pushed function: recon_boca
        function recon_bocaButtonPushed(app, event)
            if isempty(app.regionBoca)
                warndlg('Primero hay que realizar los descriptores');
            else
                regionBoca = app.regionBoca;

                % Inicializa un vector para almacenar la compacidad de cada objeto
                compacidad = zeros(length(regionBoca), 1);

                % Calcula la compacidad para cada objeto utilizando la fórmula
                for i = 1:length(regionBoca)
                    area = regionBoca(i).Area;
                    perimetro = regionBoca(i).Perimeter;
                    compacidad(i) = (4 * pi * area) / (perimetro^2);
                end

                % Obtenemos la relacion de aspecto, su inversa tambien para mas precision
                rel_aspecto = regionBoca(1).MajorAxisLength / regionBoca(1).MinorAxisLength; %2.5 a 6.5/ 3 a 6s
                rel_aspecto_inv = regionBoca(1).MinorAxisLength / regionBoca(1).MajorAxisLength; % 0.15 a 0.4

                % En base a que hemos estudiado los resultados y las bocas
                % cerradas tienes un valor de 6 o mas y las abiertas de 3 o
                % menos usamos esta informacion para obtener el porcentaje

                % Definir los valores mínimos y máximos
                valorMinimo = 6;
                valorMaximo = 3;

                % Calcular el porcentaje
                porcentaje = ((rel_aspecto - valorMinimo) / (valorMaximo - valorMinimo)) * 100;

                % Corregimos para valores por encima y por debajo del limite
                if(porcentaje > 100)
                    porcentaje = 100;
                elseif(porcentaje <0)
                    porcentaje = 0;
                end

                % Realizamos el mismo proceso pero para la inversa

                % Definir los valores mínimos y máximos
                valorMinimoInv = 0.15;
                valorMaximoInv = 0.4;

                % Calcular el porcentaje
                porcentaje_inv = ((rel_aspecto_inv - valorMinimoInv) / (valorMaximoInv - valorMinimoInv)) * 100;

                % Corregimos para valores por encima y por debajo del limite
                if(porcentaje_inv > 100)
                    porcentaje_inv = 100;
                elseif(porcentaje_inv <0)
                    porcentaje_inv = 0;
                end

                disp("porcentaje: " + porcentaje);
                disp("porcentaje_inv: " + porcentaje_inv);

                % Para mayor precision hacemos media de los dos portentajes
                porcentaje_final = (porcentaje + porcentaje_inv) / 2;
                porcentaje_final = round(porcentaje_final,2);
                disp("porcentaje_final: " + porcentaje_final);

                % Dibujamos el cuadrado alrededor de la boca y escribimos el
                % portentaje correspondiente
                fig = figure('Visible', 'off');
                imshow(app.imag_orig_mat);
                hold on;
                for i = 1:numel(regionBoca)
                    rectangle('Position', regionBoca(i).BoundingBox, 'EdgeColor', 'r', 'LineWidth', 2);
                    text(regionBoca(i).Centroid(1), regionBoca(i).BoundingBox(2) - 40, sprintf('%.2f%%', porcentaje_final), 'Color', 'red', 'FontSize', 25);
                end
                hold off;
                % Capturar la figura actual como una imagen
                frame = getframe(gca);
                imagenConRectanguloYTexto = frame.cdata;
                close(fig);

                imshow(imagenConRectanguloYTexto,'Parent',app.imag_orig,'InitialMagnification','fit');
            end
        end

        % Button pushed function: ArreglarbrilloButton
        function ArreglarbrilloButtonPushed(app, event)
            imagenOriginalConBrilloAlto = app.imag_orig_mat;

            % Convertir la imagen a double para procesamiento
            %imagenDouble = im2double(imagenOriginalConBrilloAlto);

            % Reducir el brillo multiplicando por un factor < 1
            %factor = 0.5; % Ajusta este valor según cuánto quieras reducir el brillo
            %imagenBrilloReducido = imagenDouble * factor;

            %imagenFinal = im2uint8(imagenBrilloReducido);

            % Convertir a escala de grises si es una imagen a color
            imagenOriginalConBrilloAltoBW = rgb2gray(imagenOriginalConBrilloAlto);

            % Aplicar la ecualización del histograma
            imagenEcualizada = adapthisteq(imagenOriginalConBrilloAltoBW);
            %imagenEnGrises=imgAEscalaDeGrises(app,imagenOriginalConBrilloAlto);

            %imagenFinal=imadjust(imagenEnGrises, [0 0.99], [0.45 0.8]);

            app.imagen_brillo_retocado = imagenEcualizada;
            % Visualizar la imagen original y la modificada
            imshow(imagenEcualizada);
        end

        % Value changed function: UsarvaloresautomaticosCheckBox
        function UsarvaloresautomaticosCheckBoxValueChanged(app, event)
            value = app.UsarvaloresautomaticosCheckBox.Value;

            if value==1
                set(app.reapxEditField, 'Enable', 'off');
            elseif value==0
                set(app.reapxEditField, 'Enable', 'on');
            end
        end

        % Value changed function: reapxEditField
        function reapxEditFieldValueChanged(app, event)
            value = app.reapxEditField.Value;
            %app.area_max_desc = value;
        end

        % Button pushed function: mostrar_Histograma
        function mostrar_Histograma_GrisesButtonPushed(app, event)
            if(isempty(app.imag_orig_mat))
                warndlg('No se ha seleccionado imagen');
            else
                imagenGris = imgAEscalaDeGrises(app, app.imag_orig_mat);
                if ~isempty(app.imagen_brillo_retocado)
                    imagenGris=app.imagen_brillo_retocado;
                end
                [filasOriginal, columnasOriginal, canalesOriginal] = size(imagenGris);
                tercio_inferior = round(filasOriginal / 2):filasOriginal;
                imagenRecortada=imagenGris(tercio_inferior,:,:);
                histograma = makeHistogramaImg(app, imagenRecortada);

                figure;

                bar(histograma, 'FaceColor', [0 0 0.2]);
                title('Histograma de la Imagen en escala de grises');
                xlabel('Niveles de Gris');
                ylabel('Numero de pixeles');

            end
        end

        % Button pushed function: mostrar_Histograma_Rojo
        function mostrar_Histograma_RojoButtonPushed(app, event)
            if(isempty(app.imag_orig_mat))
                warndlg('No se ha seleccionado imagen');
            else
                [R,imagenGris,B]=imsplit(app.imag_orig_mat);
                [filasOriginal, columnasOriginal, canalesOriginal] = size(imagenGris);
                tercio_inferior = round(filasOriginal / 2):filasOriginal;
                imagenRecortada=imagenGris(tercio_inferior,:,:);
                histograma = makeHistogramaImg(app, imagenRecortada);

                figure;

                bar(histograma, 'FaceColor', [0 0 0.2]);
                title('Histograma de la Imagen en el canal Rojo del RGB');
                xlabel('Niveles de Rojo');
                ylabel('Numero de pixeles');

            end
        end

        % Button pushed function: mostrar_Histograma_Grad
        function mostrar_Histograma_GradButtonPushed(app, event)
            if(isempty(app.imag_orig_mat))
                warndlg('No se ha seleccionado imagen');
            else
                imagenRecibida=imgAEscalaDeGrises(app, app.imag_orig_mat);
                if ~isempty(app.imagen_brillo_retocado)
                    imagenRecibida=app.imagen_brillo_retocado;
                end
                [Gmag,Gdir]=imgradient(imagenRecibida, 'prewitt');
                imshowpair(Gmag, Gdir, 'montage');

                title('Modulo del gradiente (Izq) y direccion del gradiente (Der)')

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.imag_orig = uiaxes(app.UIFigure);
            app.imag_orig.XTick = [];
            app.imag_orig.XTickLabel = {'[ ]'};
            app.imag_orig.YTick = [];
            app.imag_orig.Visible = 'off';
            app.imag_proc = uiaxes(app.UIFigure);
            app.imag_proc.XTick = [];
            app.imag_proc.XTickLabel = {'[ ]'};
            app.imag_proc.YTick = [];
            app.imag_proc.Visible = 'off';
            app.open_file = uibutton(app.UIFigure, 'push');
            app.open_file.ButtonPushedFcn = createCallbackFcn(app, @open_fileButtonPushed, true);
            app.open_file.Text = 'Abrir ...';
            app.saveas = uibutton(app.UIFigure, 'push');
            app.saveas.ButtonPushedFcn = createCallbackFcn(app, @saveasButtonPushed, true);
            app.saveas.Text = 'Guardar como ...';
            app.segm_partes_cara = uibutton(app.UIFigure, 'push');
            app.segm_partes_cara.ButtonPushedFcn = createCallbackFcn(app, @segm_partes_caraButtonPushed, true);
            app.segm_partes_cara.Text = 'Segmentar Partes de la cara';
            app.morf_partes_cara = uibutton(app.UIFigure, 'push');
            app.morf_partes_cara.ButtonPushedFcn = createCallbackFcn(app, @morf_partes_caraButtonPushed, true);
            app.morf_partes_cara.Text = 'Morfologia Partes de la cara';
            app.desc_partes_cara = uibutton(app.UIFigure, 'push');
            app.desc_partes_cara.ButtonPushedFcn = createCallbackFcn(app, @desc_partes_caraButtonPushed, true);
            app.desc_partes_cara.Text = 'Descriptores Partes de la cara';
            app.recon_boca = uibutton(app.UIFigure, 'push');
            app.recon_boca.ButtonPushedFcn = createCallbackFcn(app, @recon_bocaButtonPushed, true);
            app.recon_boca.Text = 'Reconocimiento boca';
            app.reset = uibutton(app.UIFigure, 'push');
            app.reset.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.reset.Text = 'Resetear imagen';
            app.segm_auto_checkbox = uicheckbox(app.UIFigure);
            app.segm_auto_checkbox.ValueChangedFcn = createCallbackFcn(app, @segm_auto_checkboxValueChanged, true);
            app.segm_auto_checkbox.Text = 'Segmentado automatico';
            app.segm_auto_checkbox.Value = true;
            app.morf_auto_checkbox = uicheckbox(app.UIFigure);
            app.morf_auto_checkbox.ValueChangedFcn = createCallbackFcn(app, @morf_auto_checkboxValueChanged, true);
            app.morf_auto_checkbox.Text = 'Usar valores automaticos';
            app.morf_auto_checkbox.Value = true;
            app.TamaodelamascaraLabel = uilabel(app.UIFigure);
            app.TamaodelamascaraLabel.Text = 'Tamaño de la mascara';
            app.morf_valorOpen_T_editText = uieditfield(app.UIFigure, 'numeric');
            app.morf_valorOpen_T_editText.Limits = [0 Inf];
            app.morf_valorOpen_T_editText.ValueDisplayFormat = '%.0f';
            app.morf_valorOpen_T_editText.ValueChangedFcn = createCallbackFcn(app, @morf_mascaraChanged, true);
            app.morf_valorOpen_T_editText.Enable = 'off';
            app.morf_valorOpen_T_editText.Value = 5;
            app.OperacionOpenLabel = uilabel(app.UIFigure);
            app.OperacionOpenLabel.Text = 'Operacion: Open';
            app.TamaodelamascaraLabel_2 = uilabel(app.UIFigure);
            app.TamaodelamascaraLabel_2.Text = 'N de iteraciones';
            app.morf_valorOpen_V_editText = uieditfield(app.UIFigure, 'numeric');
            app.morf_valorOpen_V_editText.Limits = [0 Inf];
            app.morf_valorOpen_V_editText.ValueChangedFcn = createCallbackFcn(app, @morf_mascaraChanged, true);
            app.morf_valorOpen_V_editText.Enable = 'off';
            app.morf_valorOpen_V_editText.Value = 5;
            app.OperacionCloseLabel = uilabel(app.UIFigure);
            app.OperacionCloseLabel.Text = 'Operacion: Close';
            app.TamaodelamascaraLabel_3 = uilabel(app.UIFigure);
            app.TamaodelamascaraLabel_3.Text = 'Tamaño de la mascara';
            app.morf_valorClose_T_editText = uieditfield(app.UIFigure, 'numeric');
            app.morf_valorClose_T_editText.Limits = [0 Inf];
            app.morf_valorClose_T_editText.ValueDisplayFormat = '%.0f';
            app.morf_valorClose_T_editText.ValueChangedFcn = createCallbackFcn(app, @morf_mascaraChanged, true);
            app.morf_valorClose_T_editText.Enable = 'off';
            app.morf_valorClose_T_editText.Value = 5;
            app.TamaodelamascaraLabel_4 = uilabel(app.UIFigure);
            app.TamaodelamascaraLabel_4.Text = 'N de iteraciones';
            app.morf_valorClose_V_editText = uieditfield(app.UIFigure, 'numeric');
            app.morf_valorClose_V_editText.Limits = [0 Inf];
            app.morf_valorClose_V_editText.ValueChangedFcn = createCallbackFcn(app, @morf_mascaraChanged, true);
            app.morf_valorClose_V_editText.Enable = 'off';
            app.morf_valorClose_V_editText.Value = 5;
            app.morf_sum_checkbox = uicheckbox(app.UIFigure);
            app.morf_sum_checkbox.Text = 'Aplicar sumatorio de operaciones';
            app.OriginalLabel = uilabel(app.UIFigure);
            app.OriginalLabel.Text = 'Original';
            app.ProcesoLabel = uilabel(app.UIFigure);
            app.ProcesoLabel.Text = 'Proceso';
            app.TamaolmiteobjetosaeliminarLabel = uilabel(app.UIFigure);
            app.TamaolmiteobjetosaeliminarLabel.Text = {'Tamaño límite'; 'objetos a eliminar'};
            app.reapxEditFieldLabel = uilabel(app.UIFigure);
            app.reapxEditFieldLabel.Text = 'Área (px)';
            app.reapxEditField = uieditfield(app.UIFigure, 'numeric');
            app.reapxEditField.Limits = [0 Inf];
            app.reapxEditField.ValueDisplayFormat = '%.0f';
            app.reapxEditField.ValueChangedFcn = createCallbackFcn(app, @reapxEditFieldValueChanged, true);
            app.reapxEditField.Enable = 'off';
            app.reapxEditField.Value = 1000;
            app.UsarvaloresautomaticosCheckBox = uicheckbox(app.UIFigure);
            app.UsarvaloresautomaticosCheckBox.ValueChangedFcn = createCallbackFcn(app, @UsarvaloresautomaticosCheckBoxValueChanged, true);
            app.UsarvaloresautomaticosCheckBox.Text = 'Usar valores automaticos';
            app.UsarvaloresautomaticosCheckBox.Value = true;
            app.TiposdesegmentadoLabel = uilabel(app.UIFigure);
            app.TiposdesegmentadoLabel.Text = 'Tipos de segmentado';
            app.segm_valorUmbral_editText = uieditfield(app.UIFigure, 'numeric');
            app.segm_valorUmbral_editText.Limits = [0 255];
            app.segm_valorUmbral_editText.ValueDisplayFormat = '%.0f';
            app.segm_valorUmbral_editText.Enable = 'off';
            app.segm_valorUmbralRojo_editText = uieditfield(app.UIFigure, 'numeric');
            app.segm_valorUmbralRojo_editText.Limits = [0 255];
            app.segm_valorUmbralRojo_editText.ValueDisplayFormat = '%.0f';
            app.segm_valorUmbralRojo_editText.Enable = 'off';
            app.segm_valorUmbralGrad_editText = uieditfield(app.UIFigure, 'numeric');
            app.segm_valorUmbralGrad_editText.Limits = [1 100];
            app.segm_valorUmbralGrad_editText.ValueDisplayFormat = '%.3f';
            app.segm_valorUmbralGrad_editText.Enable = 'off';
            app.segm_valorUmbralGrad_editText.Value = 1;
            app.GrisesCheckBox = uicheckbox(app.UIFigure);
            app.GrisesCheckBox.Text = 'Grises';
            app.CanalRojoCheckBox = uicheckbox(app.UIFigure);
            app.CanalRojoCheckBox.Text = 'Canal Rojo';
            app.CanalRojoCheckBox.Value = true;
            app.GradienteCheckBox = uicheckbox(app.UIFigure);
            app.GradienteCheckBox.Text = 'Gradiente';
            app.ValoresparalosumbralespersonalizadosLabel = uilabel(app.UIFigure);
            app.ValoresparalosumbralespersonalizadosLabel.Text = 'Valores para los umbrales personalizados';
            app.mostrar_Histograma = uibutton(app.UIFigure, 'push');
            app.mostrar_Histograma.ButtonPushedFcn = createCallbackFcn(app, @mostrar_Histograma_GrisesButtonPushed, true);
            app.mostrar_Histograma.Text = 'Mostrar histograma en escala de grises';
            app.imagen_superp_checkbox = uicheckbox(app.UIFigure);
            app.imagen_superp_checkbox.ValueChangedFcn = createCallbackFcn(app, @imagen_superp_checkboxValueChanged, true);
            app.imagen_superp_checkbox.Text = 'Mostrar resultado superpuesto';
            app.ArreglarbrilloButton = uibutton(app.UIFigure, 'push');
            app.ArreglarbrilloButton.ButtonPushedFcn = createCallbackFcn(app, @ArreglarbrilloButtonPushed, true);
            app.ArreglarbrilloButton.Text = 'Arreglar brillo';
            app.mostrar_Histograma_Rojo = uibutton(app.UIFigure, 'push');
            app.mostrar_Histograma_Rojo.ButtonPushedFcn = createCallbackFcn(app, @mostrar_Histograma_RojoButtonPushed, true);
            app.mostrar_Histograma_Rojo.Text = 'Mostrar histograma en el canal rojo';
            app.mostrar_Histograma_Grad = uibutton(app.UIFigure, 'push');
            app.mostrar_Histograma_Grad.ButtonPushedFcn = createCallbackFcn(app, @mostrar_Histograma_GradButtonPushed, true);
            app.mostrar_Histograma_Grad.Text = 'Mostrar Gradiente';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DeteccionSonrisa_Baldovi_Martin3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end